classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        EXITButton  matlab.ui.control.Button
        DropDown    matlab.ui.control.DropDown
        Image       matlab.ui.control.Image
    end

    
    properties (Access = private)
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
         closereq;
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Callback function
        function INFORMATIONButtonPushed(app, event)
            if(strcmp(app.DropDown.Value, 'Binary Search'))
                 web('https://www.khanacademy.org/computing/computer-science/algorithms/binary-search/a/binary-search', '-browser')
            elseif(strcmp(app.DropDown.Value, 'Sort Algorithm'))
                web('https://brilliant.org/wiki/sorting-algorithms/', '-browser')
            elseif(strcmp(app.DropDown.Value, 'Fibonacci'))
                 web('https://www.mathsisfun.com/numbers/fibonacci-sequence.html', '-browser')
            elseif(strcmp(app.DropDown.Value, 'Matrix Multiplication'))
                web('https://www.khanacademy.org/math/precalculus/x9e81a4f98389efdf:matrices/x9e81a4f98389efdf:multiplying-matrices-by-matrices/a/multiplying-matrices', '-browser')
            elseif(strcmp(app.DropDown.Value, 'Randomized Select'))
                web('https://people.engr.tamu.edu/andreas-klappenecker/csce411-s15/csce411-random4.pdf','-browser')
                
            end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            if(strcmp(app.DropDown.Value, 'Binary Search'))
                 web('https://www.khanacademy.org/computing/computer-science/algorithms/binary-search/a/binary-search', '-browser')
            elseif(strcmp(app.DropDown.Value, 'Sort Algorithm'))
                web('https://brilliant.org/wiki/sorting-algorithms/', '-browser')
            elseif(strcmp(app.DropDown.Value, 'Fibonacci'))
                 web('https://www.mathsisfun.com/numbers/fibonacci-sequence.html', '-browser')
            elseif(strcmp(app.DropDown.Value, 'Matrix Multiplication'))
                web('https://www.khanacademy.org/math/precalculus/x9e81a4f98389efdf:matrices/x9e81a4f98389efdf:multiplying-matrices-by-matrices/a/multiplying-matrices', '-browser')
            elseif(strcmp(app.DropDown.Value, 'Randomized Select'))
                web('https://people.engr.tamu.edu/andreas-klappenecker/csce411-s15/csce411-random4.pdf','-browser')
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Sort Algorithm', 'Matrix Multiplication', 'Fibonacci', 'Binary Search', 'Randomized Select'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.Value = 'Binary Search';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'images.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end