classdef help < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MailBoxUIFigure      matlab.ui.Figure
        Image                matlab.ui.control.Image
        NAMEEditFieldLabel   matlab.ui.control.Label
        NAMEEditField        matlab.ui.control.EditField
        EMAILEditFieldLabel  matlab.ui.control.Label
        EMAILEditField       matlab.ui.control.EditField
        ContactMeLabel       matlab.ui.control.Label
        SENDButton           matlab.ui.control.Button
        message_area         matlab.ui.control.TextArea
        EXITButton           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SENDButton
        function SENDButtonPushed(app, event)
            name = app.NAMEEditField.Value;
            email = app.NAMEEditField.Value;
            messageArea = app.message_area.Value;
            
            if strlength(messageArea)==0
                msgbox('Message field cannot be blank...', 'Error','error');

            else
                setpref("Internet","SMTP_Server","smtp.gmail.com");
                setpref("Internet","E_mail","itaikcu@gmail.com"); % Need to give the email ID
    
                props = java.lang.System.getProperties;
                props.setProperty("mail.smtp.auth","true");
                props.setProperty("mail.smtp.starttls.enable","true’");
    
                props.setProperty('mail.smtp.socketFactory.class', ...
                      'javax.net.ssl.SSLSocketFactory');
                props.setProperty('mail.smtp.socketFactory.port','465');
    
                setpref("Internet","SMTP_Username","itaikcu@gmail.com"); % Need to give the email ID
                setpref("Internet","SMTP_Password","zebraperde066445"); % Need to give the password
                send_message="From "+name+" "+email+" "+messageArea;
                sendmail("itaikcu@gmail.com", "FeedBack for Matlab App", send_message)
            end
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            closereq;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MailBoxUIFigure = uifigure('Visible', 'off');
            app.MailBoxUIFigure.Name = 'Mail Box';
            app.Image = uiimage(app.MailBoxUIFigure);
            app.Image.ImageSource = '340681.jpg';
            app.NAMEEditFieldLabel = uilabel(app.MailBoxUIFigure);
            app.NAMEEditFieldLabel.Text = 'NAME';
            app.NAMEEditField = uieditfield(app.MailBoxUIFigure, 'text');
            app.EMAILEditFieldLabel = uilabel(app.MailBoxUIFigure);
            app.EMAILEditFieldLabel.Text = 'E-MAIL';
            app.EMAILEditField = uieditfield(app.MailBoxUIFigure, 'text');
            app.ContactMeLabel = uilabel(app.MailBoxUIFigure);
            app.ContactMeLabel.Text = 'Contact Me';
            app.SENDButton = uibutton(app.MailBoxUIFigure, 'push');
            app.SENDButton.ButtonPushedFcn = createCallbackFcn(app, @SENDButtonPushed, true);
            app.SENDButton.Text = 'SEND';
            app.message_area = uitextarea(app.MailBoxUIFigure);
            app.EXITButton = uibutton(app.MailBoxUIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            % Show the figure after all components are created
            app.MailBoxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = help

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MailBoxUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MailBoxUIFigure)
        end
    end
end