classdef time < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TimeComparisonCreatedbyBeyzaSayraciUIFigure  matlab.ui.Figure
        INSERTIONSORTCheckBox       matlab.ui.control.CheckBox
        BUBBLESORTCheckBox          matlab.ui.control.CheckBox
        MERGESORTCheckBox           matlab.ui.control.CheckBox
        BUCKETSORTCheckBox          matlab.ui.control.CheckBox
        RADIXSORTCheckBox           matlab.ui.control.CheckBox
        QUICKSORTCheckBox           matlab.ui.control.CheckBox
        COUNTINGSORTCheckBox        matlab.ui.control.CheckBox
        HEAPSORTCheckBox            matlab.ui.control.CheckBox
        COMPAREALLMETHODSButton     matlab.ui.control.Button
        COMPARECHOSENMETHODSButton  matlab.ui.control.Button
        Button                      matlab.ui.control.Button
        MaxRangeEditField           matlab.ui.control.NumericEditField
        NumberofElementsEditField   matlab.ui.control.NumericEditField
        maximum_range               matlab.ui.control.Label
        element_numbers             matlab.ui.control.Label
        IterationEditFieldLabel     matlab.ui.control.Label
        IterationEditField          matlab.ui.control.NumericEditField
        CLEARButton                 matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        range_number;
        array_number; 
        insertion=[]
        bubble=[]
        merge=[]
        bucket=[]
        heap=[]
        radix=[]
        counting=[]
        quick=[]
        insertion_time = zeros(); 
        bubble_time = zeros();
        merge_time = zeros();
        bucket_time = zeros();
        radix_time = zeros();
        counting_time = zeros();
        quick_time = zeros();
        heap_time = zeros();
    end
    
    methods (Access = private)
        
        function result = time_comparison(app,Case,range_number,array_number,iter)
            
            rng('default');
            rng(1721);    
            RangeNumber = range_number; %define range
            runnig_number = array_number; %define number of elements
            iterNumber = iter;
            cla(app.UIAxes);
            app.insertion=[];
            app.bubble=[];
            app.merge=[];
            app.bucket=[];
            app.heap=[];
            app.radix=[];
            app.counting=[];
            app.quick=[];
            legends=[];
            
             for j = runnig_number:10:iterNumber
                for i=1:30
 
                       Random_Array = randperm(RangeNumber,j); %create random array
                  
                       max_random_array = max(Random_Array); %find the maximum value
                       
                       
                       %%%%%%insertion sort
                       if Case(1)
                       tic;
                       insertion_sort(Random_Array);
                       insertion_sort_time = toc;
                      
                       app.insertion_time(i,j) = insertion_sort_time;
                      
                       sum_insertion_sort = sum(app.insertion_time);
                       average_insertion_sort = sum_insertion_sort / 30 ; %take average insertion
                       
                       end
                      
                       %%%%%bubble sort 
                       if Case(2)
                       tic;
                       bubble_sort(Random_Array);
                       bubble_sort_time = toc;
                       
                       app.bubble_time(i,j) = bubble_sort_time;
                       sum_bubble_sort = sum(app.bubble_time);
                       average_bubble_sort = sum_bubble_sort / 30 ; %take average for bubble
                       end
                      
                       %%%%%%%merge sort 
                       if Case(3)
                       tic;
                       merge_sorti(Random_Array);
                       merge_sort_time = toc;
                       
                       app.merge_time(i,j) = merge_sort_time;
                       sum_merge_sort = sum(app.merge_time);
                       average_merge_sort = sum_merge_sort / 30;%take average for merge
                       end
                   
                       %%%%%bucket sort
                       if Case(4)
                       tic;
                       bucket_sort(Random_Array);
                       bucket_sort_time = toc;
                       
                       app.bucket_time(i,j) = bucket_sort_time;
                       sum_bucket_sort = sum(app.bucket_time);
                       average_bucket_sort = sum_bucket_sort / 30 ;%take average for radix
                       end
                      
                       %%%%%%radix sort
                       if Case(5)
                       tic;
                       radix_sort(Random_Array);
                       radix_sort_time = toc;
                       
                       app.radix_time(i,j) = radix_sort_time;
                       sum_radix_sort = sum(app.radix_time);
                       average_radix_sort = sum_radix_sort / 30 ;%take average for radix
                       end
                 
                       %%%%%counting sort
                       if Case(6)
                       tic;
                       counting_sort(Random_Array,max_random_array);
                       counting_sort_time = toc;
                       
                       app.counting_time(i,j) = counting_sort_time;
                       sum_counting_sort = sum(app.counting_time);
                       average_counting_sort = sum_counting_sort / 30;%take average for counting 
                       end
                    
                       %%%%%%quick sort 
                       if Case(7)
                       tic;
                       quicksorti(Random_Array,1,length(Random_Array));
                       quick_sort_time = toc;
                       
                       app.quick_time(i,j) = quick_sort_time;
                       sum_quick_sort = sum(app.quick_time);
                       average_quick_sort = sum_quick_sort / 30;%take avergae for quick
                       end
                     
                       %%%%%heap sort 
                       if Case(8)
                       tic;
                       heap_sort(Random_Array);
                       heap_sort_time = toc;
                       
                       app.heap_time(i,j) = heap_sort_time;
                       sum_heap_sort = sum(app.heap_time);
                       average_heap_sort = sum_heap_sort / 30;%take average for heap
                       end
                  
                end
                
                if Case(1)
                app.insertion=[app.insertion average_insertion_sort(1,j)];
                end
                
                if Case(2)
                app.bubble=[app.bubble average_bubble_sort(1,j)];
                end
               
                if Case(3)
                app.merge=[app.merge average_merge_sort(1,j)];
                end
               
                if Case(4)
                app.bucket=[app.bucket average_bucket_sort(1,j)];
                end
                
                if Case(5)
                app.radix=[app.radix average_radix_sort(1,j)];
                end
                
                if Case(6)
                app.counting=[app.counting average_counting_sort(1,j)];
                end
                 
                if Case(7)
                app.quick=[app.quick average_quick_sort(1,j)];
                end
                
                if Case(8)
                app.heap=[app.heap average_heap_sort(1,j)];
                end
            
             end
             
             
             b=runnig_number:10:iterNumber;
             %%% insertion
             if Case(1)
             hold(app.UIAxes,"on"); 
             plot(app.UIAxes,b,app.insertion,'c'); %plot graph
             legends=[legends "Insertion"];
             end
            
             %%%% bubble
             if Case(2)
             hold(app.UIAxes,"on"); 
             plot(app.UIAxes,b,app.bubble,"y"); %plot graph
             legends=[legends "Bubble"];
             end
             
              %%%% merge
             if Case(3)
             hold(app.UIAxes,"on"); 
             plot(app.UIAxes,b,app.merge,"m"); %plot graph
             legends=[legends "Merge"];
             end
             
             %%%% bucket
             if Case(4)
             hold(app.UIAxes,"on"); 
             plot(app.UIAxes,b,app.bucket,"g"); %plot graph
             legends=[legends "Bucket"];
             end
             
             %%% radix
             if Case(5)
             hold(app.UIAxes,"on"); 
             plot(app.UIAxes,b,app.radix,"r"); %plot graph
             legends=[legends "Radix"];
             end
             
             %%% counting
             if Case(6)
             hold(app.UIAxes,"on"); 
             plot(app.UIAxes,b,app.counting,"m"); %plot graph
             legends=[legends "Counting"];
             end
             
             %%% quick
             if Case(7)
             hold(app.UIAxes,"on"); 
             plot(app.UIAxes,b,app.quick,"black"); %plot graph
             legends=[legends "Quick"];
             end
             
             %%% heap
             if Case(8)
             hold(app.UIAxes,"on"); 
             plot(app.UIAxes,b,app.heap,"b"); %plot graph
             legends=[legends "Heap"];
             end

             legend(app.UIAxes,legends);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: COMPAREALLMETHODSButton
        function COMPAREALLMETHODSButtonPushed(app, event)
            all_methods=[1 1 1 1 1 1 1 1];
            cla(app.UIAxes)
            RangeNumber = app.MaxRangeEditField.Value; %define range
            runnig_number = app.NumberofElementsEditField.Value; %define number of elements
            iterNumber = app.IterationEditField.Value;
            time_comparison(app,all_methods,RangeNumber,runnig_number,iterNumber)
           
           
        end

        % Button pushed function: COMPARECHOSENMETHODSButton
        function COMPARECHOSENMETHODSButtonPushed(app, event)
             if app.HEAPSORTCheckBox.Value==0 && app.BUBBLESORTCheckBox.Value==0 && app.INSERTIONSORTCheckBox.Value==0 && app.MERGESORTCheckBox.Value==0 && app.QUICKSORTCheckBox.Value==0 && app.BUCKETSORTCheckBox.Value==0 && app.RADIXSORTCheckBox.Value==0 && app.COUNTINGSORTCheckBox.Value==0 
                msgbox('Nothing is checked...', 'Error','error');
            else
                chooice=[0 0 0 0 0 0 0 0];
            bar(app.UIAxes,[],[]);
            RangeNumber = app.MaxRangeEditField.Value; %define range
            runnig_number = app.NumberofElementsEditField.Value; %define number of elements
            iterNumber = app.IterationEditField.Value;
            if app.INSERTIONSORTCheckBox.Value
                chooice(1)=1;
            else 
                chooice(1)=0;
            end
            if app.BUBBLESORTCheckBox.Value
                chooice(2)=1;
            else 
                chooice(2)=0;
            end
            if app.MERGESORTCheckBox.Value
                chooice(3)=1;
            else 
                chooice(3)=0;
            end
            if app.BUCKETSORTCheckBox.Value
                chooice(4)=1;
            else 
                chooice(4)=0;
            end
            if app.RADIXSORTCheckBox.Value
                chooice(5)=1;
            else 
                chooice(5)=0;
            end
            if app.COUNTINGSORTCheckBox.Value
                chooice(6)=1;
            else 
                chooice(6)=0;
            end
            if app.QUICKSORTCheckBox.Value
                chooice(7)=1;
            else 
                chooice(7)=0;
            end
            if app.HEAPSORTCheckBox.Value
                chooice(8)=1;
            else 
                chooice(8)=0;
            end
            time_comparison(app,chooice,RangeNumber,runnig_number,iterNumber)
            end
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            cla(app.UIAxes);
            app.INSERTIONSORTCheckBox.Value = 0;
            app.BUBBLESORTCheckBox.Value = 0;
            app.MERGESORTCheckBox.Value = 0;
            app.BUCKETSORTCheckBox.Value = 0;
            app.QUICKSORTCheckBox.Value = 0;
            app.COUNTINGSORTCheckBox.Value = 0;
            app.HEAPSORTCheckBox.Value = 0;
            app.RADIXSORTCheckBox.Value = 0;
            app.MaxRangeEditField.Value = 1;
            app.NumberofElementsEditField.Value = 1;
            app.IterationEditField.Value = 1;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TimeComparisonCreatedbyBeyzaSayraciUIFigure = uifigure('Visible', 'off');
            app.TimeComparisonCreatedbyBeyzaSayraciUIFigure.Name = 'Time Comparison----------Created by Beyza Sayraci';
            app.INSERTIONSORTCheckBox = uicheckbox(app.TimeComparisonCreatedbyBeyzaSayraciUIFigure);
            app.INSERTIONSORTCheckBox.Text = 'INSERTION SORT';
            app.BUBBLESORTCheckBox = uicheckbox(app.TimeComparisonCreatedbyBeyzaSayraciUIFigure);
            app.BUBBLESORTCheckBox.Text = 'BUBBLE SORT';
            app.MERGESORTCheckBox = uicheckbox(app.TimeComparisonCreatedbyBeyzaSayraciUIFigure);
            app.MERGESORTCheckBox.Text = 'MERGE SORT';
            app.BUCKETSORTCheckBox = uicheckbox(app.TimeComparisonCreatedbyBeyzaSayraciUIFigure);
            app.BUCKETSORTCheckBox.Text = 'BUCKET SORT';
            app.RADIXSORTCheckBox = uicheckbox(app.TimeComparisonCreatedbyBeyzaSayraciUIFigure);
            app.RADIXSORTCheckBox.Text = 'RADIX SORT';
            app.QUICKSORTCheckBox = uicheckbox(app.TimeComparisonCreatedbyBeyzaSayraciUIFigure);
            app.QUICKSORTCheckBox.Text = 'QUICK SORT';
            app.COUNTINGSORTCheckBox = uicheckbox(app.TimeComparisonCreatedbyBeyzaSayraciUIFigure);
            app.COUNTINGSORTCheckBox.Text = 'COUNTING SORT';
            app.HEAPSORTCheckBox = uicheckbox(app.TimeComparisonCreatedbyBeyzaSayraciUIFigure);
            app.HEAPSORTCheckBox.Text = 'HEAP SORT';
            app.COMPAREALLMETHODSButton = uibutton(app.TimeComparisonCreatedbyBeyzaSayraciUIFigure, 'push');
            app.COMPAREALLMETHODSButton.ButtonPushedFcn = createCallbackFcn(app, @COMPAREALLMETHODSButtonPushed, true);
            app.COMPAREALLMETHODSButton.Text = 'COMPARE ALL METHODS';
            app.COMPARECHOSENMETHODSButton = uibutton(app.TimeComparisonCreatedbyBeyzaSayraciUIFigure, 'push');
            app.COMPARECHOSENMETHODSButton.ButtonPushedFcn = createCallbackFcn(app, @COMPARECHOSENMETHODSButtonPushed, true);
            app.COMPARECHOSENMETHODSButton.Text = 'COMPARE CHOSEN METHODS';
            app.Button = uibutton(app.TimeComparisonCreatedbyBeyzaSayraciUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = '25694 (1).png';
            app.Button.Text = '';
            app.MaxRangeEditField = uieditfield(app.TimeComparisonCreatedbyBeyzaSayraciUIFigure, 'numeric');
            app.MaxRangeEditField.Value = 1;
            app.NumberofElementsEditField = uieditfield(app.TimeComparisonCreatedbyBeyzaSayraciUIFigure, 'numeric');
            app.NumberofElementsEditField.Limits = [1 Inf];
            app.NumberofElementsEditField.Value = 1;
            app.maximum_range = uilabel(app.TimeComparisonCreatedbyBeyzaSayraciUIFigure);
            app.maximum_range.Text = 'Maximum Range';
            app.element_numbers = uilabel(app.TimeComparisonCreatedbyBeyzaSayraciUIFigure);
            app.element_numbers.Text = 'Number of Elements';
            app.IterationEditFieldLabel = uilabel(app.TimeComparisonCreatedbyBeyzaSayraciUIFigure);
            app.IterationEditFieldLabel.Text = 'Iteration';
            app.IterationEditField = uieditfield(app.TimeComparisonCreatedbyBeyzaSayraciUIFigure, 'numeric');
            app.IterationEditField.Limits = [1 Inf];
            app.IterationEditField.Value = 1;
            app.CLEARButton = uibutton(app.TimeComparisonCreatedbyBeyzaSayraciUIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.UIAxes = uiaxes(app.TimeComparisonCreatedbyBeyzaSayraciUIFigure);
            title(app.UIAxes, 'TIME COMPARISON')
            % Show the figure after all components are created
            app.TimeComparisonCreatedbyBeyzaSayraciUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = time

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TimeComparisonCreatedbyBeyzaSayraciUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TimeComparisonCreatedbyBeyzaSayraciUIFigure)
        end
    end
end